classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PlaySoundButton          matlab.ui.control.Button
        PlaymodifiedsoundButton  matlab.ui.control.Button
        CvalueSliderLabel        matlab.ui.control.Label
        CvalueSlider             matlab.ui.control.Slider
        BvalueSliderLabel        matlab.ui.control.Label
        BvalueSlider             matlab.ui.control.Slider
        DelaySliderLabel         matlab.ui.control.Label
        DelaySlider              matlab.ui.control.Slider
    end

    
    properties (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlaySoundButton
        function PlaySoundButtonPushed(app, event)
            [wav, fs] = audioread('testTrack.wav');
            soundsc(wav, fs);
        end

        % Button pushed function: PlaymodifiedsoundButton
        function PlaymodifiedsoundButtonPushed(app, event)
            [wav, fs] = audioread('testTrack.wav');
            N = 16;                             % number of interconnected delay lines
            A = eye(N);                         % feedback matrix equals to identity matrix
            T60 = 2;                            % Reverberation time -- higher value brings more echo ]1, 4[, for best results
            g_dB = -60/(T60 * fs);
            g_lin = power(10, (g_dB/20));
            
            b_gains = rand(N,1);                % Randomised values between [0, 1] for input gains b_gains
            c_gains = rand(1, N);               % Randomised values between [0, 1] for output gains c_gains
            g_gains = ones(1, N);               % Setting all g_gain values to one
            m_delays = randi([500,2000],[1,N]); % For randomised delay values between [500, 2000]
            
           
            for i=1:16                          % g_gains calculated by given equation
                g_gains(i) = power(g_lin, m_delays(i));
            end
            
            y = FDN_func(wav, A, b_gains, c_gains, g_gains, m_delays);
            
            soundsc(y, fs)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PlaySoundButton = uibutton(app.UIFigure, 'push');
            app.PlaySoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButtonPushed, true);
            app.PlaySoundButton.Text = 'Play Sound';
            app.PlaymodifiedsoundButton = uibutton(app.UIFigure, 'push');
            app.PlaymodifiedsoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaymodifiedsoundButtonPushed, true);
            app.PlaymodifiedsoundButton.Text = 'Play modified sound';
            app.CvalueSliderLabel = uilabel(app.UIFigure);
            app.CvalueSliderLabel.Text = 'C-value';
            app.CvalueSlider = uislider(app.UIFigure);
            app.CvalueSlider.Limits = [0 1];
            app.DelaySliderLabel = uilabel(app.UIFigure);
            app.DelaySliderLabel.Text = 'Delay';
            app.DelaySlider = uislider(app.UIFigure);
            app.DelaySlider.Limits = [0 1];
            app.BvalueSliderLabel = uilabel(app.UIFigure);
            app.BvalueSliderLabel.Text = 'B-value';
            app.BvalueSlider = uislider(app.UIFigure);
            app.BvalueSlider.Limits = [0 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end